classdef app6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        CapturadedatosTab             matlab.ui.container.Tab
        EjesListBox                   matlab.ui.control.ListBox
        EjesListBoxLabel              matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        CalibrarsensorButton          matlab.ui.control.Button
        EncontrarsensorButton         matlab.ui.control.Button
        GuardardatosButton            matlab.ui.control.Button
        DetenercapturaButton          matlab.ui.control.Button
        PreprocesarButton             matlab.ui.control.Button
        IniciarcapturaButton          matlab.ui.control.Button
        UIAxes2_2                     matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        HampelTab                     matlab.ui.container.Tab
        SiguienteButton_2             matlab.ui.control.Button
        UIAxes3                       matlab.ui.control.UIAxes
        ButterworthTab                matlab.ui.container.Tab
        SiguienteButton_3             matlab.ui.control.Button
        UIAxes5                       matlab.ui.control.UIAxes
        VentaneoTab                   matlab.ui.container.Tab
        ExtraerCaracteristicasButton  matlab.ui.control.Button
        UIAxes6                       matlab.ui.control.UIAxes
        CaracteristicasTab            matlab.ui.container.Tab
        ClasificacionTab              matlab.ui.container.Tab
        UIAxes7_2                     matlab.ui.control.UIAxes
    end

    properties (Access = public)
    inputData   % lo usas para almacenar datos capturados
end
    properties (Access = private)
    % --- BLE ---
    cNotify = []              % characteristic de notificación (FFE4)
    bleObj  = []              % objeto BLE
    cWrite  = []              % characteristic de escritura (FFE9)
    SelectedSensor string = ""   % nombre del sensor
    isCapturing logical = false  % indica si hay captura activa
    deviceName string = ""       % nombre del dispositivo conectado

    % --- Tiempos ---
    startTime datetime = datetime.empty      % tiempo de inicio de captura
    prevAccTime datetime = datetime.empty    % última muestra ACC
    prevGyrTime datetime = datetime.empty    % última muestra GYR
    prevAngTime datetime = datetime.empty    % última muestra ANG

    % --- Acelerómetro ---
    accBuf double = []         %  datos de Ax, Ay, Az (g)
    accIdx double = []         %  índice de muestra
    tAcc_sec double = []       % tiempo relativo (s)
    dtAcc_sec double = []      % delta t entre muestras
    sampleCounterAcc double = 0

    % --- Giroscopio ---
    gyrBuf double = []         %  datos de Gx, Gy, Gz (°/s)
    gyrIdx double = []         %  índice de muestra
    tGyr_sec double = []       % tiempo relativo (s)
    dtGyr_sec double = []      % delta t entre muestras
    sampleCounterGyr double = 0

    % --- Ángulos ---
    angBuf double = []         %  datos de AngX, AngY, AngZ (°)
    angIdx double = []         %  índice de muestra
    tAng_sec double = []       % tiempo relativo (s)
    dtAng_sec double = []      % delta t entre muestras
    sampleCounterAng double = 0

    sampleRate_Hz double = 100;
    notifyCount double = 0;

    LastSavedPath string = ""

    PreprocessedData double = []
    PreprocessedColumnName char = ''
    ButterFilteredData table = table()

    SegmentedData cell ={}
    CurrentWindow double= 0
    NumWindows double = 0
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
  
            app.inputData = table();
        end
    
        function localWrite(app, char, data)
            write(char, data);
        end 
    
        function localHandleData(app, evt)
            
            bytes = uint8(evt.Data);
            n = numel(bytes);
            i = 1;
    
            while i+10 <= n
                if bytes(i) == hex2dec('55')
                    id = bytes(i+1);
                    fr = bytes(i:i+10);  
                    switch id
                        case hex2dec('51')  % ACC
                            ax = typecast(uint8(fr(3:4)),'int16');
                            ay = typecast(uint8(fr(5:6)),'int16');
                            az = typecast(uint8(fr(7:8)),'int16');
                            acc = double([ax ay az]) / 32768 * 16;  % ±16 g
                            app.sampleCounterAcc = app.sampleCounterAcc + 1;
                            app.accBuf(end+1,:) = acc;
                            app.accIdx(end+1,1) = app.sampleCounterAcc;
    
                        case hex2dec('52')  % GYRO
                            gx = typecast(uint8(fr(3:4)),'int16');
                            gy = typecast(uint8(fr(5:6)),'int16');
                            gz = typecast(uint8(fr(7:8)),'int16');
                            gyr = double([gx gy gz]) / 32768 * 2000; % ±2000 °/s
                            app.sampleCounterGyr = app.sampleCounterGyr + 1;
                            app.gyrBuf(end+1,:) = gyr;
                            app.gyrIdx(end+1,1) = app.sampleCounterGyr;
                    end
                    i = i + 11;
                else
                    i = i + 1;
                end
            end
        end  
    
        function onBleData(app, src, ~)
           
            try
                [bytes, ts] = read(src, "latest");
            catch
                try
                    bytes = read(src);
                    ts = datetime('now');
                catch
                    return;
                end
            end
            if isempty(bytes), return; end
            bytes = uint8(bytes(:)).';   
        
            % Conteo de notificaciones (debug)
            app.notifyCount = app.notifyCount + 1;
            if app.notifyCount <= 5 || mod(app.notifyCount,50) ==0
                fprintf('[BLE] notif #%d, %d bytes\n', app.notifyCount, numel(bytes));
            end
        
            % Buffer incremental para parseo
            persistent rxBuf
            if isempty(rxBuf), rxBuf = uint8([]); end
            rxBuf = [rxBuf, bytes]; %#ok<AGROW>
        
            % Tiempo relativo (s) desde startTime
            if isempty(app.startTime), app.startTime = datetime('now'); end
            tRel = seconds(ts - app.startTime);
        
            i = 1;
            n = numel(rxBuf);
            while i <= n
                rem = n - i + 1;
        
                % --- Formato B: 20 bytes, 2º byte == 0x61 ---
                if rem >= 20 && rxBuf(i+1) == hex2dec('61')
                    fr = rxBuf(i:i+19);
        
                    
                    getS16 = @(hi,lo) double(typecast(uint16(bitor(bitshift(uint16(hi),8),uint16(lo))), 'int16'));
        
                    % Acel/gyro
                    axg = getS16(fr(4), fr(3))  / 32768 * 16;
                    ayg = getS16(fr(6), fr(5))  / 32768 * 16;
                    azg = getS16(fr(8), fr(7))  / 32768 * 16;
        
                    gxd = getS16(fr(10),fr(9))  / 32768 * 2000;
                    gyd = getS16(fr(12),fr(11)) / 32768 * 2000;
                    gzd = getS16(fr(14),fr(13)) / 32768 * 2000;
        
                    % Ángulos
                    angx = getS16(fr(16),fr(15)) / 32768 * 180;
                    angy = getS16(fr(18),fr(17)) / 32768 * 180;
                    angz = getS16(fr(20),fr(19)) / 32768 * 180;
        
                    % ---- ACC ----
                    app.sampleCounterAcc = app.sampleCounterAcc + 1;
                    app.accBuf(end+1,:)  = [axg ayg azg]; %#ok<AGROW>
                    app.accIdx(end+1,1)  = app.sampleCounterAcc; %#ok<AGROW>
                    if isempty(app.tAcc_sec), app.tAcc_sec = tRel; app.dtAcc_sec = NaN;
                    else, app.tAcc_sec(end+1,1)=tRel; app.dtAcc_sec(end+1,1)=app.tAcc_sec(end)-app.tAcc_sec(end-1); end
        
                    % ---- GYR ----
                    app.sampleCounterGyr = app.sampleCounterGyr + 1;
                    app.gyrBuf(end+1,:)  = [gxd gyd gzd]; %#ok<AGROW>
                    app.gyrIdx(end+1,1)  = app.sampleCounterGyr; %#ok<AGROW>
                    if isempty(app.tGyr_sec), app.tGyr_sec = tRel; app.dtGyr_sec = NaN;
                    else, app.tGyr_sec(end+1,1)=tRel; app.dtGyr_sec(end+1,1)=app.tGyr_sec(end)-app.tGyr_sec(end-1); end
        
                    % ---- ANG ----
                    app.sampleCounterAng = app.sampleCounterAng + 1;
                    app.angBuf(end+1,:)  = [angx angy angz]; %#ok<AGROW>
                    app.angIdx(end+1,1)  = app.sampleCounterAng; %#ok<AGROW>
                    if isempty(app.tAng_sec), app.tAng_sec = tRel; app.dtAng_sec = NaN;
                    else, app.tAng_sec(end+1,1)=tRel; app.dtAng_sec(end+1,1)=app.tAng_sec(end)-app.tAng_sec(end-1); end
        
                    i = i + 20;
                    continue
                end
        
                % --- Formato A: 11 bytes (0x55 ID …) ---
                if rem >= 11 && rxBuf(i) == hex2dec('55')
                    fr = rxBuf(i:i+10);
                    id = fr(2);
        
                    if id == hex2dec('51')   % ACC
                        ax = typecast(uint8(fr(3:4)),'int16');
                        ay = typecast(uint8(fr(5:6)),'int16');
                        az = typecast(uint8(fr(7:8)),'int16');
                        acc = double([ax ay az]) / 32768 * 16;
        
                        app.sampleCounterAcc = app.sampleCounterAcc + 1;
                        app.accBuf(end+1,:)  = acc; %#ok<AGROW>
                        app.accIdx(end+1,1)  = app.sampleCounterAcc; %#ok<AGROW>
                        if isempty(app.tAcc_sec), app.tAcc_sec = tRel; app.dtAcc_sec = NaN;
                        else, app.tAcc_sec(end+1,1)=tRel; app.dtAcc_sec(end+1,1)=app.tAcc_sec(end)-app.tAcc_sec(end-1); end
        
                    elseif id == hex2dec('52') % GYR
                        gx = typecast(uint8(fr(3:4)),'int16');
                        gy = typecast(uint8(fr(5:6)),'int16');
                        gz = typecast(uint8(fr(7:8)),'int16');
                        gyr = double([gx gy gz]) / 32768 * 2000;
        
                        app.sampleCounterGyr = app.sampleCounterGyr + 1;
                        app.gyrBuf(end+1,:)  = gyr; %#ok<AGROW>
                        app.gyrIdx(end+1,1)  = app.sampleCounterGyr; %#ok<AGROW>
                        if isempty(app.tGyr_sec), app.tGyr_sec = tRel; app.dtGyr_sec = NaN;
                        else, app.tGyr_sec(end+1,1)=tRel; app.dtGyr_sec(end+1,1)=app.tGyr_sec(end)-app.tGyr_sec(end-1); end
        
                    elseif id == hex2dec('53') % ANG
                        angx = typecast(uint8(fr(3:4)),'int16');
                        angy = typecast(uint8(fr(5:6)),'int16');
                        angz = typecast(uint8(fr(7:8)),'int16');
                        ang  = double([angx angy angz]) / 32768 * 180;
        
                        app.sampleCounterAng = app.sampleCounterAng + 1;
                        app.angBuf(end+1,:)  = ang; %#ok<AGROW>
                        app.angIdx(end+1,1)  = app.sampleCounterAng; %#ok<AGROW>
                        if isempty(app.tAng_sec), app.tAng_sec = tRel; app.dtAng_sec = NaN;
                        else, app.tAng_sec(end+1,1)=tRel; app.dtAng_sec(end+1,1)=app.tAng_sec(end)-app.tAng_sec(end-1); end
                    end
        
                    i = i + 11;
                    continue
                end
        
                % Si no hay frame completo todavía, salimos
                break
            end
        
           
            rxBuf = rxBuf(i:end);
        end
        function T = buildExportTable(app)
            % Alinea por el mayor número de muestras entre Acc/Gyr/Ang y
            % arma una sola tabla con metadatos, tiempos relativos e índices.
        
            nA = size(app.accBuf,1);
            nG = size(app.gyrBuf,1);
            nN = size(app.angBuf,1);
            N  = max([nA nG nN]);
            if N==0
                error("No hay datos en buffers.");
            end
        
            % Prealocar columnas (NaN donde no aplique)
            Device   = repmat(string(app.deviceName), N, 1);
            StartedAt= repmat(string(datestr(app.startTime,'yyyy-mm-dd HH:MM:SS.FFF')), N, 1);
        
            % ACC
            IdxAcc = nan(N,1); tAcc_s = nan(N,1); dtAcc_s = nan(N,1);
            Ax_g   = nan(N,1); Ay_g   = nan(N,1); Az_g    = nan(N,1);
            if nA>0
                IdxAcc(1:nA) = app.accIdx;
                Ax_g(1:nA) = app.accBuf(:,1);  Ay_g(1:nA) = app.accBuf(:,2);  Az_g(1:nA) = app.accBuf(:,3);
                if ~isempty(app.tAcc_sec),  tAcc_s(1:nA)  = app.tAcc_sec;  end
                if ~isempty(app.dtAcc_sec), dtAcc_s(1:nA) = app.dtAcc_sec; end
            end
        
            % GYR
            IdxGyr = nan(N,1); tGyr_s = nan(N,1); dtGyr_s = nan(N,1);
            Gx_dps = nan(N,1); Gy_dps = nan(N,1); Gz_dps = nan(N,1);
            if nG>0
                IdxGyr(1:nG) = app.gyrIdx;
                Gx_dps(1:nG) = app.gyrBuf(:,1);  Gy_dps(1:nG) = app.gyrBuf(:,2);  Gz_dps(1:nG) = app.gyrBuf(:,3);
                if ~isempty(app.tGyr_sec),  tGyr_s(1:nG)  = app.tGyr_sec;  end
                if ~isempty(app.dtGyr_sec), dtGyr_s(1:nG) = app.dtGyr_sec; end
            end
        
            % ANG (si no los usas, puedes comentar esta sección)
            IdxAng   = nan(N,1); tAng_s = nan(N,1); dtAng_s = nan(N,1);
            AngX_deg = nan(N,1); AngY_deg = nan(N,1); AngZ_deg = nan(N,1);
            if nN>0
                IdxAng(1:nN)   = app.angIdx;
                AngX_deg(1:nN) = app.angBuf(:,1);  AngY_deg(1:nN) = app.angBuf(:,2);  AngZ_deg(1:nN) = app.angBuf(:,3);
                if ~isempty(app.tAng_sec),  tAng_s(1:nN)  = app.tAng_sec;  end
                if ~isempty(app.dtAng_sec), dtAng_s(1:nN) = app.dtAng_sec; end
            end
        
            % Armar tabla final
            T = table( ...
                Device, StartedAt, ...
                IdxAcc, tAcc_s, dtAcc_s, Ax_g, Ay_g, Az_g, ...
                IdxGyr, tGyr_s, dtGyr_s, Gx_dps, Gy_dps, Gz_dps, ...
                IdxAng, tAng_s, dtAng_s, AngX_deg, AngY_deg, AngZ_deg);
        end
        function plotFromTable(app, T)
            % Limpia ejes
            cla(app.UIAxes2); cla(app.UIAxes2_2);
        
            % Obtener qué ejes graficar
            try
                selectedAxes = app.EjesListBox.Value;
            catch
                selectedAxes = {'Ax','Ay','Az','Gx','Gy','Gz'}; % por si no existe
            end
        
            % ===== Acelerómetro =====
            hold(app.UIAxes2, 'off');
            maskA = ~isnan(T.IdxAcc) & all(~isnan([T.Ax_g T.Ay_g T.Az_g]),2);
            if any(maskA)
                if ismember('Ax',selectedAxes), plot(app.UIAxes2, T.IdxAcc(maskA), T.Ax_g(maskA)); hold(app.UIAxes2,'on'); end
                if ismember('Ay',selectedAxes), plot(app.UIAxes2, T.IdxAcc(maskA), T.Ay_g(maskA)); hold(app.UIAxes2,'on'); end
                if ismember('Az',selectedAxes), plot(app.UIAxes2, T.IdxAcc(maskA), T.Az_g(maskA)); hold(app.UIAxes2,'on'); end
                title(app.UIAxes2,'Aceleración (g)');
                xlabel(app.UIAxes2,'Muestras'); ylabel(app.UIAxes2,'g');
                legend(app.UIAxes2, intersect({'Ax','Ay','Az'},selectedAxes), 'Location','best');
            else
                title(app.UIAxes2,'Aceleración (g) — sin datos');
            end
        
            % ===== Giroscopio =====
            hold(app.UIAxes2_2, 'off');
            maskG = ~isnan(T.IdxGyr) & all(~isnan([T.Gx_dps T.Gy_dps T.Gz_dps]),2);
            if any(maskG)
                if ismember('Gx',selectedAxes), plot(app.UIAxes2_2, T.IdxGyr(maskG), T.Gx_dps(maskG)); hold(app.UIAxes2_2,'on'); end
                if ismember('Gy',selectedAxes), plot(app.UIAxes2_2, T.IdxGyr(maskG), T.Gy_dps(maskG)); hold(app.UIAxes2_2,'on'); end
                if ismember('Gz',selectedAxes), plot(app.UIAxes2_2, T.IdxGyr(maskG), T.Gz_dps(maskG)); hold(app.UIAxes2_2,'on'); end
                title(app.UIAxes2_2,'Giroscopio (°/s)');
                xlabel(app.UIAxes2_2,'Muestras'); ylabel(app.UIAxes2_2,'°/s');
                legend(app.UIAxes2_2, intersect({'Gx','Gy','Gz'},selectedAxes), 'Location','best');
            else
                title(app.UIAxes2_2,'Giroscopio (°/s) — sin datos');
            end
        
            drawnow;
        end 
        function filtered = hampel_filter(app, data, window_size)
                filtered = data;
                k = floor(window_size / 2);
                n = length(data);
        
                for i = (k + 1):(n - k)
                    window = data(i - k : i + k);
                    median_val = median(window, 'omitnan');
                    mad_val = median(abs(window - median_val), 'omitnan');
                    threshold = 1.4826 * mad_val;
        
                    if abs(data(i) - median_val) > threshold
                        filtered(i) = median_val;
                    end
                end    
        end
        function T = buildInputTableFromBuffers(app)
            % Arma una tabla con los nombres que ya usas al guardar
            nA = size(app.accBuf,1);
            nG = size(app.gyrBuf,1);
            nN = size(app.angBuf,1);
            N  = max([nA nG nN 0]);
        
            idxAcc = nan(N,1);  Ax = nan(N,1); Ay = nan(N,1); Az = nan(N,1);
            tAcc   = nan(N,1);  dtAcc = nan(N,1);
            idxGyr = nan(N,1);  Gx = nan(N,1); Gy = nan(N,1); Gz = nan(N,1);
            tGyr   = nan(N,1);  dtGyr = nan(N,1);
            idxAng = nan(N,1);  AngX = nan(N,1); AngY = nan(N,1); AngZ = nan(N,1);
            tAng   = nan(N,1);  dtAng = nan(N,1);
        
            if nA>0
                idxAcc(1:nA) = app.accIdx;
                Ax(1:nA)     = app.accBuf(:,1);
                Ay(1:nA)     = app.accBuf(:,2);
                Az(1:nA)     = app.accBuf(:,3);
                if ~isempty(app.tAcc_sec),  tAcc(1:nA)  = app.tAcc_sec;  end
                if ~isempty(app.dtAcc_sec), dtAcc(1:nA) = app.dtAcc_sec; end
            end
            if nG>0
                idxGyr(1:nG) = app.gyrIdx;
                Gx(1:nG)     = app.gyrBuf(:,1);
                Gy(1:nG)     = app.gyrBuf(:,2);
                Gz(1:nG)     = app.gyrBuf(:,3);
                if ~isempty(app.tGyr_sec),  tGyr(1:nG)  = app.tGyr_sec;  end
                if ~isempty(app.dtGyr_sec), dtGyr(1:nG) = app.dtGyr_sec; end
            end
            if nN>0
                idxAng(1:nN) = app.angIdx;
                AngX(1:nN)   = app.angBuf(:,1);
                AngY(1:nN)   = app.angBuf(:,2);
                AngZ(1:nN)   = app.angBuf(:,3);
                if ~isempty(app.tAng_sec),  tAng(1:nN)  = app.tAng_sec;  end
                if ~isempty(app.dtAng_sec), dtAng(1:nN) = app.dtAng_sec; end
            end
        
            devName = repmat(string(app.deviceName), N, 1);
            started = repmat(string(datestr(app.startTime,'yyyy-mm-dd HH:MM:SS.FFF')), N, 1);
        
            T = table( ...
                devName, started, ...
                idxAcc, tAcc, dtAcc, Ax, Ay, Az, ...
                idxGyr, tGyr, dtGyr, Gx, Gy, Gz, ...
                idxAng, tAng, dtAng, AngX, AngY, AngZ, ...
                'VariableNames', { ...
                    'Device','StartedAt', ...
                    'IdxAcc','tAcc_s','dtAcc_s','Ax_g','Ay_g','Az_g', ...
                    'IdxGyr','tGyr_s','dtGyr_s','Gx_dps','Gy_dps','Gz_dps', ...
                    'IdxAng','tAng_s','dtAng_s','AngX_deg','AngY_deg','AngZ_deg' ...
                });
        end 


            function y = butter_lowpass_filter(app, data, cutoff, fs, order)
                % Valida entradas
                data = double(data(:));
                if isempty(data) || ~isnumeric(data) || all(isnan(data))
                    error('butter_lowpass_filter:DataInvalid','La señal de entrada está vacía o no es numérica.');
                end
                if ~isscalar(fs) || ~isscalar(cutoff) || ~isscalar(order)
                    error('butter_lowpass_filter:ParamsInvalid','fs/cutoff/order deben ser escalares numéricos.');
                end
                if cutoff <= 0 || cutoff >= fs/2
                    error('butter_lowpass_filter:CutoffInvalid','cutoff debe estar entre (0, fs/2).');
                end
        
                % Filtro Butterworth
                nyq = 0.5*fs;
                Wn  = cutoff/nyq;
                [b,a] = butter(order, Wn, 'low');
                y = filtfilt(b, a, data);


        end

        % Button pushed function: IniciarcapturaButton
        function IniciarcapturaButtonPushed(app, event)
       
            % --- UUIDs del servicio y characteristics del WT901BLE ---
            SVC_FFE5 = "0000FFE5-0000-1000-8000-00805F9A34FB";
            CHR_FFE4 = "0000FFE4-0000-1000-8000-00805F9A34FB";  % Notify
            CHR_FFE9 = "0000FFE9-0000-1000-8000-00805F9A34FB";  % Write
        
            try
                %% 1) Validar estado y selección
                if app.isCapturing
                    uialert(app.UIFigure, "La captura ya está en curso.", "Aviso");
                    return;
                end
                if strlength(app.SelectedSensor) == 0
                    uialert(app.UIFigure, "Primero selecciona un sensor BLE.", "Falta selección");
                    return;
                end
        
                %% 2) Conectar
                fprintf("[BLE] Conectando a %s ...\n", app.SelectedSensor);
                app.bleObj = ble(app.SelectedSensor);
                pause(0.4);
                app.deviceName = string(app.bleObj.Name);
                fprintf("[BLE] Conectado a %s\n", app.deviceName);
        
                %% 3) Obtener las characteristics del servicio FFE5
                app.cNotify = characteristic(app.bleObj, SVC_FFE5, CHR_FFE4);
                app.cWrite  = characteristic(app.bleObj, SVC_FFE5, CHR_FFE9);
        
                %% 4) Configurar frecuencia a 100 Hz (comando FF AA 03 09 00)
                setRate100Hz = uint8([255 170 3 9 0]);
                try
                    write(app.cWrite, setRate100Hz, "withoutresponse");
                catch
                    write(app.cWrite, setRate100Hz);
                end
                pause(0.2);
                fprintf("[BLE] Frecuencia configurada: 100 Hz\n");
        
                %% 5) Iniciar transmisión continua (FF AA 27 3A 00)
                startCmd = uint8([255 170 39 58 0]);
                try
                    write(app.cWrite, startCmd, "withoutresponse");
                catch
                    write(app.cWrite, startCmd);
                end
                pause(0.3);
                fprintf("[BLE] Transmisión iniciada.\n");
        
                %% 6) Reset de buffers y contadores
                app.sampleRate_Hz = 100;
                app.notifyCount   = 0;
                app.startTime     = datetime('now');
        
                app.accBuf = zeros(0,3); app.accIdx = zeros(0,1); app.sampleCounterAcc = 0;
                app.gyrBuf = zeros(0,3); app.gyrIdx = zeros(0,1); app.sampleCounterGyr = 0;
                app.angBuf = zeros(0,3); app.angIdx = zeros(0,1); app.sampleCounterAng = 0;
        
                app.tAcc_sec = []; app.dtAcc_sec = [];
                app.tGyr_sec = []; app.dtGyr_sec = [];
                app.tAng_sec = []; app.dtAng_sec = [];
        
                %% 7) Suscribirse a notificaciones BLE
                subscribe(app.cNotify, "notification");
                app.cNotify.DataAvailableFcn = @(src,evt) app.onBleData(src,evt);
        
                %% 8) Configurar gráficos
                app.isCapturing = true;
                cla(app.UIAxes2); cla(app.UIAxes2_2);
                title(app.UIAxes2, 'Aceleración (g)');
                xlabel(app.UIAxes2, 'Muestras'); ylabel(app.UIAxes2, 'g');
        
                title(app.UIAxes2_2, 'Giroscopio (°/s)');
                xlabel(app.UIAxes2_2, 'Muestras'); ylabel(app.UIAxes2_2, '°/s');
        
                fprintf("[BLE] Captura iniciada a 100 Hz.\n");
                uialert(app.UIFigure, "Captura iniciada. Los datos se almacenan en memoria.", "En curso");
        
            catch ME
                %% 9) Limpieza en caso de error
                try
                    if ~isempty(app.cNotify)
                        app.cNotify.DataAvailableFcn = [];
                        unsubscribe(app.cNotify, "notification");
                    end
                end
                try
                    if ~isempty(app.bleObj)
                        clear app.bleObj;
                    end
                end
                app.isCapturing = false;
                uialert(app.UIFigure, sprintf(" Error al iniciar captura:\n%s", ME.message), "Error");
                fprintf("[BLE] Error: %s\n", ME.message);
            end
               
        end

        % Button pushed function: PreprocesarButton
        function PreprocesarButtonPushed(app, event)

            % 1) Cambiar de pestaña
            app.TabGroup.SelectedTab = app.HampelTab;
        
            % 2) Validar que hay datos en app.inputData
            if isempty(app.inputData) || ~istable(app.inputData)
                uialert(app.UIFigure,'Primero guarda o importa un CSV para tener app.inputData.','Error');
                return;
            end
        
            % 3) Detectar una columna Y típica (puedes ampliar esta lista si quieres)
            vars = app.inputData.Properties.VariableNames;
            cand = contains(vars, {'AccelerationY','Ay_g','Ay','AccY','AccelY'}, 'IgnoreCase', true);
            if ~any(cand)
                uialert(app.UIFigure,'No se encontró una columna de Y (p.ej. Ay, Ay_g, AccelerationY).','Error');
                return;
            end
            colName = vars{find(cand,1,'first')};
            y = app.inputData.(colName);
            if istable(y), y = table2array(y); end
            y = double(y(:));
            if ~isnumeric(y) || isempty(y) || all(isnan(y))
                uialert(app.UIFigure, sprintf('La columna %s no tiene datos numéricos válidos.', colName), 'Error');
                return;
            end
        
            % 4) Aplicar Hampel (UNA salida)
            W = 15;  % ventana impar recomendada
            yFilt = app.hampel_filter(y, W);
        
            % 5) Graficar en la pestaña Hampel
            ax = app.UIAxes3;
            cla(ax);
            plot(ax, y, 'DisplayName','Original'); hold(ax,'on');
            plot(ax, yFilt, 'LineWidth',1.2, 'DisplayName','Hampel');
            hold(ax,'off'); grid(ax,'on');
            title(ax, sprintf('Hampel en %s (W=%d)', colName, W), 'Interpreter','none');
            xlabel(ax,'Muestras'); ylabel(ax,'Aceleración Y (g)');
            legend(ax,'show');
        
            % 6) Guardar resultado para pasos siguientes (opcional)
            app.PreprocessedData       = yFilt;        % crea estas props si no existen
            app.PreprocessedColumnName = colName;
        
        end

        % Button pushed function: EncontrarsensorButton
        function FindSensorButtonPushed(app, event)

            try
                % Escaneo robusto (3 intentos, ordenado por RSSI)
                all = table();
                for k = 1:3
                    pause(0.3)
                    t = blelist;      % evita conflicto si existe una variable 'blelist'
                    all = [all; t];           %#ok<AGROW>
                end
                if isempty(all)
                    uialert(app.UIFigure,'No se detectaron dispositivos BLE. Reintenta.','Sin resultados');
                    return;
                end
        
                % Quitar duplicados por Address y ordenar por RSSI
                [~,idx] = unique(all.Address,'stable');
                devs = all(idx,:);
                devs = sortrows(devs,'RSSI','descend');
        
                % Filtrar candidatos típicos WT*
                keys = ["WT901","WT","WIT","JY901","WT9"];
                mask = false(height(devs),1);
                for i=1:numel(keys)
                    mask = mask | contains(upper(string(devs.Name)), upper(keys(i)));
                end
                cand = devs(mask,:);
                if isempty(cand), cand = devs; end
        
                % Construir Items (texto que ve el usuario) e ItemsData (MAC real)
                items = strcat(string(cand.Name), " (", string(cand.Address), ")  | RSSI=", string(cand.RSSI));
                data  = string(cand.Address);
        
                % Cargar al DropDown (tu control se llama "DropDown")
                app.DropDown.Items     = cellstr(items);
                app.DropDown.ItemsData = cellstr(data);
        
                % Seleccionar el primero por comodidad
                if ~isempty(data)
                    app.DropDown.Value = app.DropDown.ItemsData{1};
                    app.SelectedSensor = string(app.DropDown.Value);
                end
        
                if any(mask)
                    uialert(app.UIFigure, sprintf('Se encontraron %d WT*. Selecciona el tuyo.', sum(mask)), 'Éxito');
                else
                    uialert(app.UIFigure, 'No se detectó WT*; se muestran todos los BLE cercanos.', 'Aviso');
                end
        
            catch err
                % Mensaje útil si hay “shadowing” de blelist
                extra = "";
                if contains(err.message, 'Too many output') || contains(err.message,'Undefined')
                    extra = sprintf('\nSugerencia:\n  >> which blelist -all\n  >> clear blelist');
                end
                uialert(app.UIFigure, "Error durante la búsqueda:\n" + err.message + extra, 'Error');
            end

        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)

            app.SelectedSensor = string(app.DropDown.Value);
            fprintf("Sensor seleccionado: %s\n", app.SelectedSensor);
        end

        % Button pushed function: CalibrarsensorButton
        function CalibrarsensorButtonPushed(app, event)
        
            try
                % 1) Validaciones básicas
                addr = app.SelectedSensor;
                if strlength(addr) == 0
                    uialert(app.UIFigure,'Primero selecciona un sensor BLE en el menú.','Falta selección'); 
                    return;
                end
                if app.isCapturing
                    uialert(app.UIFigure,'Detén la captura antes de calibrar.','Aviso'); 
                    return;
                end
        
                % 2) Conexión temporal
                b = ble(addr);
                pause(0.3);
        
                % 3) Obtener directamente la characteristic FFE9 del servicio FFE5
                svcUUID  = "0000FFE5-0000-1000-8000-00805F9A34FB";
                chUUID   = "0000FFE9-0000-1000-8000-00805F9A34FB";
                cWrite   = characteristic(b, svcUUID, chUUID);   % <<-- R2025a
        
                % 4) Comandos oficiales (5 bytes)
                CALIB_ACCEL = uint8([255 170 1 1 0]);   % FF AA 01 01 00
                EXIT_CAL    = uint8([255 170 1 0 0]);   % FF AA 01 00 00
                SAVE_CFG    = uint8([255 170 0 0 0]);   % FF AA 00 00 00
        
                uialert(app.UIFigure,"Coloca el sensor inmóvil y plano. Iniciando calibración del acelerómetro…","Calibrando");
        
                % 5) Escribir (algunos adaptadores requieren 'withoutresponse')
                try
                    write(cWrite, CALIB_ACCEL, "withoutresponse"); pause(3);
                    write(cWrite, EXIT_CAL,    "withoutresponse"); pause(0.3);
                    write(cWrite, SAVE_CFG,    "withoutresponse");
                catch
                    % fallback sin tercer argumento
                    write(cWrite, CALIB_ACCEL); pause(3);
                    write(cWrite, EXIT_CAL);    pause(0.3);
                    write(cWrite, SAVE_CFG);
                end
        
                % 6) Cerrar conexión temporal
                clear b  % (evita llamar delete sobre objeto ble)
        
                uialert(app.UIFigure,' Calibración del acelerómetro completada y guardada.','Éxito');
        
            catch ME
                clear b
                uialert(app.UIFigure, sprintf(" Error al calibrar acelerómetro:\n%s", ME.message), 'Error');
            end


        end

        % Button pushed function: DetenercapturaButton
        function DetenercapturaButtonPushed(app, event)
      
            try
                if ~app.isCapturing
                    uialert(app.UIFigure,"No hay captura en curso.","Aviso"); return;
                end
                app.isCapturing = false;
                try, if ~isempty(app.cNotify), unsubscribe(app.cNotify,"notification"); end, catch, end
                try, if ~isempty(app.bleObj),   clear app.bleObj;                   end, catch, end
                uialert(app.UIFigure,"Captura detenida. Ya puedes guardar y graficar.","Éxito");
            catch ME
                uialert(app.UIFigure, sprintf(" Error al detener:\n%s", ME.message), "Error");
            end
        

        end

        % Button pushed function: GuardardatosButton
        function GuardardatosButtonPushed(app, event)

            try
                nA = size(app.accBuf,1);
                nG = size(app.gyrBuf,1);
                nN = size(app.angBuf,1);
                if nA==0 && nG==0 && nN==0
                    uialert(app.UIFigure,"No hay datos para guardar.","Sin datos");
                    return;
                end
        
                % 1) Construir tabla desde buffers (misma estructura que el CSV)
                T = app.buildInputTableFromBuffers();
        
                % 2) Diálogo y guardado
                ts = datestr(now,'yyyymmdd_HHMMSS');
                [fname, pth] = uiputfile('*.csv','Guardar CSV…', "captura_"+ts+".csv");
                if isequal(fname,0), return; end
                out = fullfile(pth, fname);
                writetable(T, out);
        
                % 3) Actualizar “último archivo guardado” y preparar inputData
                app.LastSavedPath = string(out);
                app.inputData     = T;   % ← ya queda listo para Preprocesar
        
                % 4) (Opcional) Ploteo rápido post-guardado
                try
                    axA = app.UIAxes2; axG = app.UIAxes2_2;
                    cla(axA); cla(axG);
                    maskA = ~isnan(T.IdxAcc) & all(~isnan([T.Ax_g T.Ay_g T.Az_g]),2);
                    if any(maskA)
                        plot(axA, T.IdxAcc(maskA), [T.Ax_g(maskA), T.Ay_g(maskA), T.Az_g(maskA)]);
                        title(axA,'Aceleración (g)'); xlabel(axA,'Muestras'); ylabel(axA,'g');
                        legend(axA,{'Ax','Ay','Az'},'Location','best');
                    else
                        title(axA,'Aceleración (g) — sin datos válidos');
                    end
                    maskG = ~isnan(T.IdxGyr) & all(~isnan([T.Gx_dps T.Gy_dps T.Gz_dps]),2);
                    if any(maskG)
                        plot(axG, T.IdxGyr(maskG), [T.Gx_dps(maskG), T.Gy_dps(maskG), T.Gz_dps(maskG)]);
                        title(axG,'Giroscopio (°/s)'); xlabel(axG,'Muestras'); ylabel(axG,'°/s');
                        legend(axG,{'Gx','Gy','Gz'},'Location','best');
                    else
                        title(axG,'Giroscopio (°/s) — sin datos válidos');
                    end
                catch
                    % No pasa nada si falla el plot
                end
        
                uialert(app.UIFigure, "Datos guardados:\n" + string(out) + ...
                    "\n\nListos para 'Preprocesar' sin importar.", "Éxito");
        
            catch ME
                uialert(app.UIFigure, sprintf("Error al guardar:\n%s", ME.message), "Error");
            end
        
        end

        % Button pushed function: SiguienteButton_2
        function SiguienteButtonPushed(app, event)
        
        try
            % Cambiar a la pestaña Butterworth
            app.TabGroup.SelectedTab = app.ButterworthTab;
        
            % Verifica que ya tengas Hampel listo
            if isempty(app.PreprocessedData)
                uialert(app.UIFigure, 'Primero aplica el filtro Hampel antes de continuar.', 'Error');
                return;
            end
        
            % Parámetros del filtro
            fs     = app.sampleRate_Hz;  % 100 por defecto
            cutoff = 5;                  % Hz
            order  = 4;
        
            % Señal preprocesada (Hampel)
            signal_hampel = app.PreprocessedData(:);
        
            % Aplica Butterworth (tu función privada)
            filtered_butter = app.butter_lowpass_filter(signal_hampel, cutoff, fs, order);
        
            % Grafica en la pestaña Butterworth
            ax = app.UIAxes5;
            cla(ax);
            plot(ax, signal_hampel, 'r', 'DisplayName', 'Hampel'); hold(ax, 'on');
            plot(ax, filtered_butter, 'LineWidth', 1.2, 'DisplayName', 'Butterworth');
            hold(ax, 'off'); grid(ax, 'on'); legend(ax, 'show');
            title(ax, 'Filtro Hampel + Butterworth');
            xlabel(ax, 'Muestras'); ylabel(ax, 'Aceleración (g)');
        
            % Guarda el resultado para pasos siguientes (opcional)
            app.ButterFilteredData = filtered_butter;
        
        catch ME
            uialert(app.UIFigure, sprintf('Error en Butterworth:\n%s', ME.message), 'Error');
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes7_2 = uiaxes(app.UIFigure);
            title(app.UIAxes7_2, 'Title')
            xlabel(app.UIAxes7_2, 'X')
            ylabel(app.UIAxes7_2, 'Y')
            zlabel(app.UIAxes7_2, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CapturadedatosTab = uitab(app.TabGroup);
            app.CapturadedatosTab.Title = 'Captura de datos';
            app.UIAxes2 = uiaxes(app.CapturadedatosTab);
            title(app.UIAxes2, 'Acceleration (g)')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.CapturadedatosTab);
            title(app.UIAxes2_2, 'Giroscopio (°/s)')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.IniciarcapturaButton = uibutton(app.CapturadedatosTab, 'push');
            app.IniciarcapturaButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarcapturaButtonPushed, true);
            app.IniciarcapturaButton.Text = 'Iniciar captura';
            app.PreprocesarButton = uibutton(app.CapturadedatosTab, 'push');
            app.PreprocesarButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocesarButtonPushed, true);
            app.PreprocesarButton.Text = 'Preprocesar';
            app.DetenercapturaButton = uibutton(app.CapturadedatosTab, 'push');
            app.DetenercapturaButton.ButtonPushedFcn = createCallbackFcn(app, @DetenercapturaButtonPushed, true);
            app.DetenercapturaButton.Text = 'Detener captura';
            app.GuardardatosButton = uibutton(app.CapturadedatosTab, 'push');
            app.GuardardatosButton.ButtonPushedFcn = createCallbackFcn(app, @GuardardatosButtonPushed, true);
            app.GuardardatosButton.Text = 'Guardar datos';
            app.EncontrarsensorButton = uibutton(app.CapturadedatosTab, 'push');
            app.EncontrarsensorButton.ButtonPushedFcn = createCallbackFcn(app, @FindSensorButtonPushed, true);
            app.EncontrarsensorButton.Text = 'Encontrar sensor';
            app.CalibrarsensorButton = uibutton(app.CapturadedatosTab, 'push');
            app.CalibrarsensorButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrarsensorButtonPushed, true);
            app.CalibrarsensorButton.Text = 'Calibrar sensor';
            app.DropDown = uidropdown(app.CapturadedatosTab);
            app.DropDown.Items = {'"— pulsa ''Encontrar sensor'' —"'};
            app.DropDown.ItemsData = {'""'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '""';
            app.EjesListBoxLabel = uilabel(app.CapturadedatosTab);
            app.EjesListBoxLabel.Text = 'Ejes ';
            app.EjesListBox = uilistbox(app.CapturadedatosTab);
            app.EjesListBox.Items = {'Ax', 'Ay', 'Az', 'Gx', 'Gy', 'Gz'};
            app.EjesListBox.ItemsData = {'Ax', 'Ay', 'Az', 'Gx', 'Gy', 'Gz'};
            app.EjesListBox.Multiselect = 'on';
            app.EjesListBox.Value = {'Ax', 'Ay', 'Az', 'Gx', 'Gy', 'Gz'};
            app.HampelTab = uitab(app.TabGroup);
            app.HampelTab.Title = 'Hampel';
            app.UIAxes3 = uiaxes(app.HampelTab);
            title(app.UIAxes3, 'Acceleration Y(g)')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.SiguienteButton_2 = uibutton(app.HampelTab, 'push');
            app.SiguienteButton_2.ButtonPushedFcn = createCallbackFcn(app, @SiguienteButtonPushed, true);
            app.SiguienteButton_2.Text = 'Siguiente';
            app.ButterworthTab = uitab(app.TabGroup);
            app.ButterworthTab.Title = 'Butterworth';
            app.UIAxes5 = uiaxes(app.ButterworthTab);
            title(app.UIAxes5, 'Acceleration Y(g)')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.SiguienteButton_3 = uibutton(app.ButterworthTab, 'push');
            app.SiguienteButton_3.Text = 'Siguiente';
            app.VentaneoTab = uitab(app.TabGroup);
            app.VentaneoTab.Title = 'Ventaneo';
            app.UIAxes6 = uiaxes(app.VentaneoTab);
            title(app.UIAxes6, 'Acceleration Y(g)')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.ExtraerCaracteristicasButton = uibutton(app.VentaneoTab, 'push');
            app.ExtraerCaracteristicasButton.Text = 'Extraer Caracteristicas';
            app.CaracteristicasTab = uitab(app.TabGroup);
            app.CaracteristicasTab.Title = 'Caracteristicas';
            app.ClasificacionTab = uitab(app.TabGroup);
            app.ClasificacionTab.Title = 'Clasificacion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end